classdef InitialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StartPanel                    matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Image2                        matlab.ui.control.Image
        WelcometoChirpAQILabel        matlab.ui.control.Label
        ClickanywheretocontinueLabel  matlab.ui.control.Label
        Button                        matlab.ui.control.Button
    end

    properties (Access = private)
        SharedData % Holds the datasets passed from the welcome screen 
    end

    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
                % Fallback data loading directly inside the Welcome App
                fprintf('Loading datasets...\n');
                
                % Locate the Data directory relative to the welcome app file path
                appDir = fileparts(mfilename('fullpath'));
                dataRoot = fullfile(appDir, 'Data');
                
                try
                    co2_data = load(fullfile(dataRoot, 'co2_concentration.mat'));
                    temp_data = load(fullfile(dataRoot, 'temperature_humidity.mat'));
                    vent_data = load(fullfile(dataRoot, 'ventilation_system_status.mat'));
                    voc_data = load(fullfile(dataRoot, 'voc_concentration.mat'));

                    preferredTheme = "dark";

                    % Pack everything into our property struct
                    app.SharedData = struct(...
                        'co2', co2_data.co2_concentration, ...
                        'temp', temp_data.temperature_humidity, ...
                        'vent', vent_data.ventilation_status, ...
                        'voc', voc_data.voc_concentration, ...
                        'preferredTheme', preferredTheme ...
                    );
                    fprintf('Datasets loaded successfully inside welcome!\n');
                catch ME
                    errordlg('Could not locate data files in the "Data/" folder!', 'Load Error');
                    rethrow(ME);
                end
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Launch the roles app, passing our loaded data structure to it
            roles(app.SharedData);
            
            % Close and destroy the welcome screen
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartPanel = uipanel(app.UIFigure);
            app.StartPanel.Title = 'StartPanel';
            app.GridLayout = uigridlayout(app.StartPanel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.ClickanywheretocontinueLabel = uilabel(app.GridLayout);
            app.ClickanywheretocontinueLabel.Text = 'Click anywhere to continue';
            app.WelcometoChirpAQILabel = uilabel(app.GridLayout);
            app.WelcometoChirpAQILabel.Text = 'Welcome to Chirp AQI';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Cricket_Logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InitialApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end