classdef dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DasboardPanel           matlab.ui.container.Panel
        AQI                     matlab.ui.control.LinearGauge
        AQILabel                matlab.ui.control.Label
        GraphTypeDropDown       matlab.ui.control.DropDown
        GraphTypeDropDownLabel  matlab.ui.control.Label
        AreaDropDown            matlab.ui.control.DropDown
        AreaDropDownLabel       matlab.ui.control.Label
        WorkoutAdviceTextArea   matlab.ui.control.TextArea
        WorkoutAdviceLabel      matlab.ui.control.Label
        AQIAlertLabel           matlab.ui.control.Label
        AirQualityGraphLabel    matlab.ui.control.Label
        AQIGaugeLabel           matlab.ui.control.Label
        FansLabel               matlab.ui.control.Label
        FanSwitch               matlab.ui.control.Switch
        AirQualityGraph         matlab.ui.control.UIAxes
        ManagerAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MyData % Holds the dataset for structures
        UserRole % "Manager" or "User" | Depends on what chosen in roles.mlapp

        OriginalFigureSize
        OriginalComponentPositions
    end
    
    methods (Access = private)
        
        % Function feeding the Gauge
        function score = DisplayAQI(app, selection)
            % This function calculates a dynamic score based on user selection
            % and the loaded datasets.
            switch selection
                % Example calculation rules:
                case 'Cardio Zone'
                    score = AQI_Calc(app.MyData.temp.temperature_C, ...
                                     app.MyData.co2.co2_ppm, ...
                                     app.MyData.temp.relative_humidity_pct,...
                                     app.MyData.voc.tvoc_ppb,...
                                     app.MyData.temp.zone_name,...
                                     "cardio_zone");
                case 'Free Weight Zone'
                    score = AQI_Calc(app.MyData.temp.temperature_C, ...
                                     app.MyData.co2.co2_ppm, ...
                                     app.MyData.temp.relative_humidity_pct,...
                                     app.MyData.voc.tvoc_ppb,...
                                     app.MyData.temp.zone_name,...
                                     "free_weight_zone");
                case 'Front Desk Lobby'
                    score = AQI_Calc(app.MyData.temp.temperature_C, ...
                                     app.MyData.co2.co2_ppm, ...
                                     app.MyData.temp.relative_humidity_pct,...
                                     app.MyData.voc.tvoc_ppb,...
                                     app.MyData.temp.zone_name,...
                                     "front_desk_lobby");
                case 'Locker Room'
                    score = AQI_Calc(app.MyData.temp.temperature_C, ...
                                     app.MyData.co2.co2_ppm, ...
                                     app.MyData.temp.relative_humidity_pct,...
                                     app.MyData.voc.tvoc_ppb,...
                                     app.MyData.temp.zone_name,...
                                     "locker_room");
                case 'Yoga Studio'
                    score = AQI_Calc(app.MyData.temp.temperature_C, ...
                                     app.MyData.co2.co2_ppm, ...
                                     app.MyData.temp.relative_humidity_pct,...
                                     app.MyData.voc.tvoc_ppb,...
                                     app.MyData.temp.zone_name,...
                                     "yoga_studio");
                otherwise
                    score = 0; % Default value
            end
            
            % Limit gauge boundaries safely (0-500)
            score = max(0, min(score, 500));
        end

        % Primary Plotting Function
            function updatePrimaryPlot(app)
                % Clear current plot
                cla(app.AirQualityGraph);
    
                % Identify selection from dropdown
                selectedData = app.GraphTypeDropDown.Value;
    
                hold(app.AirQualityGraph, 'on');
                
                xlabel(app.AirQualityGraph, 'Time (Seconds)');
    
                switch selectedData
                    % Makes each case of graph
                    case 'CO2 Concentration'
    
                        legend_labels = make_lineplot(app.AirQualityGraph, ...
                                                      app.MyData.co2.time_sec, ...
                                                      app.MyData.co2.co2_ppm, ...
                                                      app.MyData.co2.zone_name);
    
                        title(app.AirQualityGraph, 'Zone Air Quality Analysis (CO2)');
                        app.AirQualityGraph.Title.Color = 'white';
                        ylabel(app.AirQualityGraph, 'CO2 Concentration (PPM)');
    
                    case 'VOC Concentration'
    
                        legend_labels = make_lineplot(app.AirQualityGraph, ...
                                                      app.MyData.voc.time_sec, ...
                                                      app.MyData.voc.tvoc_ppb, ...
                                                      app.MyData.voc.zone_name);
    
                        title(app.AirQualityGraph, 'Zone Air Quality Analysis (VOC)');
                        app.AirQualityGraph.Title.Color = 'white';
                        ylabel(app.AirQualityGraph, 'VOC Concentration (PPB)');
    
                    case 'Temperature'
    
                        legend_labels = make_lineplot(app.AirQualityGraph, ...
                                                      app.MyData.temp.time_sec, ...
                                                      app.MyData.temp.temperature_C, ...
                                                      app.MyData.temp.zone_name);
    
                        title(app.AirQualityGraph, 'Zone Air Quality Analysis (Temperature)');
                        app.AirQualityGraph.Title.Color = 'white';
                        ylabel(app.AirQualityGraph, 'Temperature (C)');
    
                    case 'Humidity'
                        
                        legend_labels = make_lineplot(app.AirQualityGraph, ...
                                                      app.MyData.temp.time_sec, ...
                                                      app.MyData.temp.relative_humidity_pct, ...
                                                      app.MyData.temp.zone_name);
    
                        title(app.AirQualityGraph, 'Zone Air Quality Analysis (Humidity)');
                        ylabel(app.AirQualityGraph, 'Humidity (PCT)');
    
                    otherwise
                        %The default graph is temperature
                        legend_labels = make_lineplot(app.AirQualityGraph, ...
                                                      app.MyData.temp.time_sec, ...
                                                      app.MyData.temp.temperature_C, ...
                                                      app.MyData.temp.zone_name);
    
                        title(app.AirQualityGraph, 'Zone Air Quality Analysis (Temperature)');
                        ylabel(app.AirQualityGraph, 'Temperature (C)');
                end
                hold(app.AirQualityGraph, 'off');
    
                % Guard against empty legend outputs to prevent layout crashes
    
                if exist('legend_labels', 'var') && ~isempty(legend_labels)
                    legend(app.AirQualityGraph, legend_labels, 'FontSize', 6, 'Location', 'best');
                end 
    
            end

        
        % Helper to update alert color and text based on AQI value
        function updateAQIAlert(app, aqiValue)
            if aqiValue <= 50
                % Green (Good)
                app.AQIAlertLabel.Text = sprintf('Air Quality: Good (%d)', round(aqiValue));
                app.AQIAlertLabel.BackgroundColor = [0.18, 0.80, 0.44]; % Soft Green
                app.AQIAlertLabel.FontColor = [1, 1, 1]; % White Text
                
            elseif aqiValue <= 100
                % Yellow (Moderate)
                app.AQIAlertLabel.Text = sprintf('Air Quality: Moderate (%d)', round(aqiValue));
                app.AQIAlertLabel.BackgroundColor = [0.95, 0.77, 0.06]; % Yellow
                app.AQIAlertLabel.FontColor = [0, 0, 0]; % Black Text for Contrast
                
            elseif aqiValue <= 150
                % Orange (Unhealthy for Sensitive Groups)
                app.AQIAlertLabel.Text = sprintf('Air Quality: Unhealthy for Sensitive Groups (%d)', round(aqiValue));
                app.AQIAlertLabel.BackgroundColor = [0.90, 0.49, 0.13]; % Orange
                app.AQIAlertLabel.FontColor = [1, 1, 1];
                
            elseif aqiValue <= 200
                % Red (Unhealthy)
                app.AQIAlertLabel.Text = sprintf('Air Quality: Unhealthy! (%d)', round(aqiValue));
                app.AQIAlertLabel.BackgroundColor = [0.90, 0.30, 0.24]; % Red
                app.AQIAlertLabel.FontColor = [1, 1, 1];
                
            elseif aqiValue <= 300
                % Purple (Very Unhealthy)
                app.AQIAlertLabel.Text = sprintf('Air Quality: Very Unhealthy!! (%d)', round(aqiValue));
                app.AQIAlertLabel.BackgroundColor = [0.61, 0.35, 0.71]; % Purple
                app.AQIAlertLabel.FontColor = [1, 1, 1];
                
            else
                % Maroon (Hazardous)
                app.AQIAlertLabel.Text = sprintf('Air Quality: HAZARDOUS!!! (%d)', round(aqiValue));
                app.AQIAlertLabel.BackgroundColor = [0.48, 0.08, 0.15]; % Maroon
                app.AQIAlertLabel.FontColor = [1, 1, 1];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.OriginalFigureSize = app.UIFigure.Position(3:4);
            app.OriginalComponentPositions = containers.Map('KeyType','char','ValueType','any');

            storePositions(app, app.UIFigure);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @resizeApp, true);
        %app.UIFigure.WindowState = 'maximized';
           % Captures passed data and role parameters
            if nargin > 1 && ~isempty(varargin) && ~isempty(varargin{1})
                app.MyData = varargin{1}; 
                
                % ROBUST EXTRACTION: Checks both direct and nested arguments
                if numel(varargin) >= 2
                    app.UserRole = varargin{2};
                elseif iscell(varargin{1}) && numel(varargin{1}) >= 2
                    % Fallback for nested parameter lists
                    app.MyData = varargin{1}{1};
                    app.UserRole = varargin{1}{2};
                else
                    app.UserRole = "User"; % Safe default if only data was passed
                end
            else
                % Backup fallback so you can run the dashboard file directly for testing
                app.UserRole = "Manager"; 
                air_reference = readtable("Data/air_quality_reference.csv");
                co2_data = load("Data/co2_concentration.mat");
                temp_data = load("Data/temperature_humidity.mat");
                vent_data = load("Data/ventilation_system_status.mat");
                voc_data = load("Data/voc_concentration.mat");
                app.MyData = struct(...
                    'air_reference', air_reference, ...
                    'co2', co2_data.co2_concentration, ...
                    'temp', temp_data.temperature_humidity, ...
                    'vent', vent_data.ventilation_status, ...
                    'voc', voc_data.voc_concentration ...
                );
            end

            app.GraphTypeDropDown.Items = {'CO2 Concentration', 'VOC Concentration', 'Temperature', 'Humidity'};
            app.GraphTypeDropDown.Value = 'CO2 Concentration';
            app.AreaDropDown.Items = {'Cardio Zone', 'Free Weight Zone', 'Front Desk Lobby', 'Locker Room', 'Yoga Studio'};
            app.AreaDropDown.Value = 'Cardio Zone'; 

            % Dynamic UI adaptations based on role selection
            if strcmp(app.UserRole, "Manager")
                app.ManagerAxes.Visible = 'on';
                app.FanSwitch.Visible = 'on';
                app.FansLabel.Visible = 'on';
                app.WorkoutAdviceTextArea.Visible = 'off';
                app.WorkoutAdviceLabel.Visible = 'off';

                % Draw something on the manager axes as a placeholder
                make_lineplot(app.ManagerAxes, ...
                    app.MyData.vent.time_sec, ...
                    app.MyData.vent.supply_airflow_m3_s, ...
                    app.MyData.vent.zone_name);

                title(app.ManagerAxes, 'Supply Airflow in Areas');
                app.ManagerAxes.Title.Color = 'white';
                xlabel(app.ManagerAxes, "Time (seconds)");
                ylabel(app.ManagerAxes, "Supply Airflow (m^3/s)");

            else
                app.ManagerAxes.Visible = 'off';
                app.FanSwitch.Visible = 'off';
                app.FansLabel.Visible = 'off';
                app.WorkoutAdviceTextArea.Visible = 'on';
                app.WorkoutAdviceLabel.Visible = 'on';
                app.WorkoutAdviceTextArea.Value = {'Welcome, Gym Member!', 'Remember to stay hydrated and stretch before doing exercise.'};
            end
            
            % Render initial data states
            updatePrimaryPlot(app);
            initialAQI = DisplayAQI(app, app.AreaDropDown.Value);
            app.AQI.Value = initialAQI;
            
            % Update the colored alert label on startup
            app.updateAQIAlert(initialAQI);
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            updatePrimaryPlot(app);            
        end

        % Value changed function: AreaDropDown
        function AreaDropDownValueChanged(app, event)
            % Compute value and feed to the Gauge scale
            AQIValue = DisplayAQI(app, app.AreaDropDown.Value);
            app.AQI.Value = AQIValue;

            %Change alert color 
            app.updateAQIAlert(AQIValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DasboardPanel = uipanel(app.UIFigure);
            app.DasboardPanel.Title = 'Dasboard';
            app.ManagerAxes = uiaxes(app.DasboardPanel);
            title(app.ManagerAxes, 'Title')
            xlabel(app.ManagerAxes, 'X')
            ylabel(app.ManagerAxes, 'Y')
            zlabel(app.ManagerAxes, 'Z')
            app.AirQualityGraph = uiaxes(app.DasboardPanel);
            title(app.AirQualityGraph, 'Title')
            xlabel(app.AirQualityGraph, 'X')
            ylabel(app.AirQualityGraph, 'Y')
            zlabel(app.AirQualityGraph, 'Z')
            app.FanSwitch = uiswitch(app.DasboardPanel, 'slider');
            app.FansLabel = uilabel(app.DasboardPanel);
            app.FansLabel.Text = 'Fans:';
            app.AQIGaugeLabel = uilabel(app.DasboardPanel);
            app.AQIGaugeLabel.Text = 'AQI Gauge';
            app.AirQualityGraphLabel = uilabel(app.DasboardPanel);
            app.AirQualityGraphLabel.Text = 'Air Quality Graph';
            app.AQIAlertLabel = uilabel(app.DasboardPanel);
            app.AQIAlertLabel.Text = 'Air Quality Status';
            app.WorkoutAdviceLabel = uilabel(app.DasboardPanel);
            app.WorkoutAdviceLabel.Text = 'Workout Advice:';
            app.WorkoutAdviceTextArea = uitextarea(app.DasboardPanel);
            app.WorkoutAdviceTextArea.Editable = 'off';
            app.AreaDropDownLabel = uilabel(app.DasboardPanel);
            app.AreaDropDownLabel.Text = 'Area';
            app.AreaDropDown = uidropdown(app.DasboardPanel);
            app.AreaDropDown.Items = {'Cardio Zone', 'Free Weight Zone', 'Front Desk Lobby', 'Locker Room', 'Yoga Studio'};
            app.AreaDropDown.ValueChangedFcn = createCallbackFcn(app, @AreaDropDownValueChanged, true);
            app.AreaDropDown.Value = 'Cardio Zone';
            app.GraphTypeDropDownLabel = uilabel(app.DasboardPanel);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.DasboardPanel);
            app.GraphTypeDropDown.Items = {'CO2 Concentration', 'VOC Concentration', 'Temperature', 'Humidity'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'CO2 Concentration';
            app.AQILabel = uilabel(app.DasboardPanel);
            app.AQILabel.Text = 'AQI';
            app.AQI = uigauge(app.DasboardPanel, 'linear');
            app.AQI.Limits = [0 500];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dashboard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end