classdef graphtry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        MainPanel           matlab.ui.container.Panel
        Image_2             matlab.ui.control.Image
        ManagerLabel        matlab.ui.control.Label
        WhatsyourroleLabel  matlab.ui.control.Label
        Button_3            matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            cla(app.UIAxes);

            loaded_data = load("Data/co2_concentration.mat"); 

            % Access the nested struct holding the vectors
            co2_struct = loaded_data.co2_concentration; 

            % Pass the vectors directly from the struct to your plotting function
            make_lineplot(app.UIAxes, ...
                co2_struct.time_sec, ...
                co2_struct.co2_ppm, ...
                co2_struct.zone_name, ...
                1000);

            % Set the labels directly targeting the app axes
            xlabel(app.UIAxes, 'Time (Seconds)');
            ylabel(app.UIAxes, 'CO2 Concentration (PPM)');
            title(app.UIAxes, 'Zone Air Quality Analysis');

            % Ensure limits scale correctly to fit all 21,600 data points
            xlim(app.UIAxes, 'auto');
            ylim(app.UIAxes, 'auto');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'MainPanel';
            app.UIAxes = uiaxes(app.MainPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button_3 = uibutton(app.MainPanel, 'push');
            app.Button_3.Text = '';
            app.WhatsyourroleLabel = uilabel(app.MainPanel);
            app.WhatsyourroleLabel.Text = 'What''s your role?';
            app.ManagerLabel = uilabel(app.MainPanel);
            app.ManagerLabel.Text = 'Manager';
            app.Image_2 = uiimage(app.MainPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'cog2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphtry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end