classdef roles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RolesPanel          matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        User_Photo          matlab.ui.control.Image
        UserLabel           matlab.ui.control.Label
        User_Button         matlab.ui.control.Button
        Manager_Image       matlab.ui.control.Image
        ManagerLabel        matlab.ui.control.Label
        WhatsyourroleLabel  matlab.ui.control.Label
        ManagerButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        MyData % Holds the datasets passed from the welcome screen
    end
    
    methods (Access = private)
        
        function launchDashboard(app, roleType)
        % Launches dashboard app passing the dataset and the role
        dashboard(app.MyData, roleType);
        
        % Closes the roles screen
        delete(app.UIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Capture the data passed from the welcome screen
            if nargin > 1 && ~isempty(varargin)
                app.MyData = varargin{1};

                % Debugging message in the command window
                fprintf('Data received successfully in roles!\n');
            end

        end

        % Button pushed function: ManagerButton
        function ManagerButtonPushed(app, event)
            launchDashboard(app, "Manager");
        end

        % Button pushed function: User_Button
        function User_ButtonPushed(app, event)
            launchDashboard(app, "User");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RolesPanel = uipanel(app.UIFigure);
            app.RolesPanel.Title = 'Roles';
            app.GridLayout = uigridlayout(app.RolesPanel);
            app.ManagerButton = uibutton(app.GridLayout, 'push');
            app.ManagerButton.ButtonPushedFcn = createCallbackFcn(app, @ManagerButtonPushed, true);
            app.ManagerButton.Text = '';
            app.WhatsyourroleLabel = uilabel(app.GridLayout);
            app.WhatsyourroleLabel.Text = 'What''s your role? ';
            app.ManagerLabel = uilabel(app.GridLayout);
            app.ManagerLabel.Text = 'Manager';
            app.Manager_Image = uiimage(app.GridLayout);
            app.Manager_Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'Manager.png');
            app.User_Button = uibutton(app.GridLayout, 'push');
            app.User_Button.ButtonPushedFcn = createCallbackFcn(app, @User_ButtonPushed, true);
            app.User_Button.Text = '';
            app.UserLabel = uilabel(app.GridLayout);
            app.UserLabel.Text = 'User';
            app.User_Photo = uiimage(app.GridLayout);
            app.User_Photo.ImageSource = fullfile(pathToMLAPP, 'Images', 'Gym_User.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end